classdef STABM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ManualAlignmentButton     matlab.ui.control.Button
        SelectinputfolderButton   matlab.ui.control.Button
        StartButton               matlab.ui.control.Button
        EditField_2               matlab.ui.control.EditField
        FromEditField_2           matlab.ui.control.NumericEditField
        EditField                 matlab.ui.control.EditField
        FromEditField             matlab.ui.control.NumericEditField
        SetoutputfpsButton        matlab.ui.control.Button
        SelectoutputfolderButton  matlab.ui.control.Button
    end


    properties (Access = private)
        FrameData
        VideoFrameRate
        OutputFolder
        inputFolder
        imageFiles
        ProgressBar                 % Progress bar dialog
    end

    methods (Access = private)

        function [sorted, index] = sort_nat(~, cellArray)
            % Natural order sort for strings with numbers (e.g., img1, img2, img10)

            expr = '\d+';
            numPart = regexp(cellArray, expr, 'match');

            numVal = cellfun(@(x) str2double(x{1}), numPart);
            [~, index] = sort(numVal);
            sorted = cellArray(index);
        end


        function updateProgressBar(app, message, value)
            % Update progress bar with message and value
            if isvalid(app.ProgressBar)
                app.ProgressBar.Message = message;
                app.ProgressBar.Value = value;
                drawnow;
            end
        end

        function closeProgressBar(app)
            % Close progress bar if it exists
            if ~isempty(app.ProgressBar) && isvalid(app.ProgressBar)
                close(app.ProgressBar);
                app.ProgressBar = [];
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.FromEditField_2.Value = 0; % Default value for output FPS
        end

        % Button pushed function: SetoutputfpsButton
        function SetoutputfpsButtonPushed(app, event)
            prompt = {'Enter the desired FPS:'}; % question
            dlgtitle = 'Set Output FPS'; % dialog title
            dims = [1 35];
            definput = {num2str(app.FromEditField.Value)}; % Default value
            response = inputdlg(prompt, dlgtitle, dims, definput);

            if ~isempty(response)
                fps_output = str2double(response{1});

                % Check if the input is a valid number
                if isnan(fps_output)
                    uialert(app.UIFigure, 'Please enter a valid number.', 'Error');
                    return;
                end

                % Check if the value is negative or zero
                if fps_output <= 0
                    uialert(app.UIFigure, 'FPS must be greater than zero.', 'Error');
                    return;
                end

                % If everything is valid, update the field
                app.FromEditField_2.Value = fps_output;
            end
        end

        % Button pushed function: SelectoutputfolderButton
        function SelectoutputfolderButtonPushed(app, event)
            folderPath = uigetdir('', 'Select the output folder');
            if folderPath ~= 0
                app.OutputFolder = folderPath;
                uialert(app.UIFigure, ['Selected folder: ' folderPath], 'Output Folder');
            else
                uialert(app.UIFigure, 'No folder selected.', 'Warning');
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Retrieve frame data and total number of frames
            Frame_Data = app.FrameData;
            numFrames = numel(Frame_Data);
            outputFolder = app.OutputFolder;

            % Check if output folder has been selected
            if isempty(outputFolder)
                uialert(app.UIFigure, 'Please select an output folder first.', 'Error');
                return;
            end

            % Ask user for frame range to align (e.g., from 10 to 50)
            prompt = {'Start frame index:', 'End frame index:'};
            dlgtitle = 'Manual Alignment Range';
            dims = [1 35];
            definput = {'1', num2str(numFrames)};
            risposta = inputdlg(prompt, dlgtitle, dims, definput);

            % If user presses "Cancel", exit function
            if isempty(risposta)
                return;
            end

            % Convert input values to numbers
            startFrame = str2double(risposta{1});
            endFrame = str2double(risposta{2});

            % Check if values are valid
            if isnan(startFrame) || isnan(endFrame) || startFrame < 1 || endFrame > numFrames || startFrame > endFrame
                uialert(app.UIFigure, 'Invalid frame range.', 'Error');
                return;
            end

            % Define selected frames for alignment
            selectedFrames = startFrame:endFrame;
            nSelected = numel(selectedFrames); % Total number of frames to align
            alignedFrames = Frame_Data; % Initially all frames are identical

            % Create progress bar in the main app window
            d = uiprogressdlg(app.UIFigure, ...
                'Title', 'Stabilizing...', ...
                'Message', 'Initializing...', ...
                'Indeterminate', 'off', ...
                'Cancelable', 'off');

            % For each selected frame...
            for idx = 1:nSelected
                i = selectedFrames(idx);               % Original frame index
                currentFrame = Frame_Data{i};           % Get current frame
                currentGray = im2gray(currentFrame);   % Convert to grayscale

                % Show a new window for point selection with zoom and pan enabled
                f = figure('Name', sprintf('Frame %d', i), 'NumberTitle', 'off');
                imshow(currentFrame);                  % Show color frame
                drawnow;

                zoom on; pan on;                             % Enable zoom and pan
                title({'Zoom/pan to find a fixed point.', ...
                    'When ready, press ENTER to confirm and start selection.'});

                % Wait for ENTER key to confirm zoom/pan is done
                waitforbuttonpress;
                while ~strcmp(get(gcf, 'CurrentCharacter'), char(13))  % char(13) = Enter key
                    waitforbuttonpress;  % Wait again until Enter is pressed
                end

                zoom off; pan off;                          % Disable zoom and pan
                title('Now click on the fixed background point');

                % Wait for click on a background point
                [xClick, yClick] = ginput(1);
                close(f);                              % Close window after click

                % Create a template (image portion) around the clicked point
                windowSize = 31;                       % Window size (e.g., 31x31)
                halfWin = floor(windowSize / 2);       % Half window size
                x = round(xClick);                     % Rounded x coordinate
                y = round(yClick);                     % Rounded y coordinate

                % Ensure template stays within image boundaries
                x = max(halfWin+1, min(size(currentGray,2)-halfWin, x));
                y = max(halfWin+1, min(size(currentGray,1)-halfWin, y));

                % Crop template around selected point
                template = currentGray(y-halfWin:y+halfWin, x-halfWin:x+halfWin);
                refPoint = [x, y];                     % Reference point (clicked pixel)

                % Perform correlation between template and entire frame
                correlation = normxcorr2(template, currentGray);

                % Find point in frame most similar to template
                [~, maxIndex] = max(correlation(:));
                [ypeak, xpeak] = ind2sub(size(correlation), maxIndex);

                % Calculate displacement between clicked point and found point
                corr_offset = [(xpeak - size(template,2)), (ypeak - size(template,1))];
                dx = refPoint(1) - corr_offset(1); % Horizontal offset
                dy = refPoint(2) - corr_offset(2); % Vertical offset

                % Apply translation to align frame
                alignedFrames{i} = imtranslate(currentFrame, [dx, dy]);

                % Update progress bar
                d.Value = idx / nSelected;
                d.Message = sprintf('Aligning frame %d of %d...', idx, nSelected);
            end

            % Save all frames to video, including non-stabilized ones
            fps = app.FromEditField_2.Value; % Selected frame rate

            % Build output file name
            [~, baseName] = fileparts(app.inputFolder);
            outputVideoName = baseName + "_manual_aligned.mp4";
            outputPath = fullfile(outputFolder, outputVideoName);

            writer = VideoWriter(outputPath, 'MPEG-4');
            writer.FrameRate = fps;
            open(writer);

            for i = 1:numFrames
                writeVideo(writer, alignedFrames{i});
            end

            close(writer);
            d.Value = 1;
            d.Message = 'Done!';
            pause(0.5);
            close(d);

            uialert(app.UIFigure, 'Manual alignment completed and video saved!', 'Success');
        end

        % Button pushed function: SelectinputfolderButton
        function SelectinputfolderButtonPushed(app, event)
            app.inputFolder = uigetdir(pwd, 'Select frame folder');

            if app.inputFolder == 0
                uialert(app.UIFigure, 'No folder selected', 'Warning');
                return;
            end

            % Load the list of images
            app.imageFiles = dir(fullfile(app.inputFolder, '*.png'));
            if isempty(app.imageFiles)
                uialert(app.UIFigure, 'No PNG files found in the folder', 'Error');
                return;
            end

            % Sort files numerically
            [~, idx] = app.sort_nat({app.imageFiles.name});
            app.imageFiles = app.imageFiles(idx);

            uialert(app.UIFigure, sprintf('Loaded %d frames', length(app.imageFiles)), 'Success');

            % Try to recover the FPS from VideoStructure if STABM was launched by ViFoSe
            fps = 30;  % Default value

            try
                % If VideoStructure exists in the workspace (passed from ViFoSe)
                if evalin('base', 'exist(''VideoStructure'', ''var'')')
                    VideoStructure = evalin('base', 'VideoStructure');
                    if isfield(VideoStructure, 'frameRate')
                        fps = VideoStructure.frameRate;
                        uialert(app.UIFigure, ...
                            sprintf('FPS loaded from ViFoSe: %.2f', fps), ...
                            'FPS Detected');
                    else
                        error('VideoStructure exists but frameRate field not found');
                    end
                else
                    % If STABM is launched standalone, use default FPS.
                    uialert(app.UIFigure, ...
                        'Using default FPS = 30. For original FPS, launch from ViFoSe.', ...
                        'Info', 'Icon', 'info');
                end
            catch ME
                uialert(app.UIFigure, ...
                    sprintf('Could not read video FPS. Using default FPS = 30.\nError: %s', ME.message), ...
                    'Warning', 'Icon', 'warning');
            end

            % Load each image into Frame_Data
            Frame_Data = cell(1, length(app.imageFiles));
            for i = 1:length(app.imageFiles)
                Frame_Data{i} = imread(fullfile(app.inputFolder, app.imageFiles(i).name));
            end

            % Save the extracted data in the app's properties for later use
            app.FrameData = Frame_Data;

            % Also save the frame rate in a separate property
            app.VideoFrameRate = fps;

            % Update input fields with the video information
            app.FromEditField.Value = app.VideoFrameRate; % Original video FPS
            app.FromEditField_2.Value = app.VideoFrameRate; % Default FPS for the stabilized video (same as original)
        end

        % Button pushed function: ManualAlignmentButton
        function ManualAlignmentButtonPushed(app, event)
            infoText = {
                ' STABM - Manual Video Stabilization Tool';
                '';
                ' ==============================================================================';
                ' AUTHOR: [Abdelrahman Abdelaziz Mohamed]';
                '';
                ' DATE  : [26/10/2025]';
                '';
                ' FUNCTION: STABM - Manual Frame Alignment';
                '';
                ' PURPOSE:';
                '';
                ' This MATLAB application performs manual video stabilization by allowing users';
                ' to select specific reference points in each frame for precise alignment.';
                ' The tool is designed for scenarios where automatic stabilization methods fail';
                ' or when maximum precision is required, particularly in scientific analysis,';
                ' microscopy, medical imaging, and technical applications where specific';
                ' reference points must be maintained throughout the video sequence.';
                '';
                ' FUNCTIONAL OVERVIEW:';
                '';
                ' 1. Accepts a folder containing sequential PNG frames as input.';
                ' 2. Automatically detects and sorts frames using natural number ordering.';
                ' 3. Allows user to select specific frame range for alignment.';
                ' 4. For each frame, provides interactive zoom/pan to identify reference points.';
                ' 5. Uses normalized cross-correlation to precisely align frames based on';
                '    user-selected reference points.';
                ' 6. Applies calculated translations to stabilize the entire sequence.';
                ' 7. Generates output video with user-defined frame rate.';
                '';
                ' CRITICAL REQUIREMENT:';
                '';
                ' - The user MUST click on exactly the same physical point in every frame.';
                ' - Even small pixel selection errors can accumulate and destabilize the entire sequence.';
                ' - Maximum precision is essential for successful stabilization.';
                '';
                ' INPUT PARAMETERS:';
                '';
                ' - inputFolder: Directory containing PNG frame sequence.';
                ' - FrameData: Cell array of loaded image frames.';
                ' - outputFPS: Desired frame rate for output video.';
                ' - outputFolder: Destination for processed/stabilized video.';
                ' - frameRange: Start and end frames for alignment process.';
                '';
                ' OUTPUT:';
                ' - Stabilized MP4 video file saved to specified output folder.';
                ' - Maintains original video quality while applying manual alignment corrections.';
                '';
                ' ALGORITHM DETAILS:';
                '';
                ' - Uses interactive point selection with zoom/pan capabilities for precision.';
                ' - Implements normalized cross-correlation (normxcorr2) for template matching.';
                ' - Calculates precise displacement vectors between selected points and matched templates.';
                ' - Applies image translation using imtranslate function.';
                '';
                ' FEATURES:';
                '';
                ' - Interactive frame-by-frame point selection with visual feedback.';
                ' - Zoom and pan functionality for precise point identification.';
                ' - Real-time progress tracking during alignment process.';
                ' - Support for partial frame range alignment.';
                ' - High precision alignment suitable for scientific applications.';
                '';
                ' CRITICAL USER GUIDELINES:';
                '';
                ' 1. Choose a distinctive, stable reference point that is visible in all frames.';
                ' 2. Use zoom and pan features to ensure pixel-level accuracy in point selection.';
                ' 3. Select EXACTLY the same physical feature in every frame - consistency is paramount.';
                ' 4. Avoid points that may move or change appearance between frames.';
                ' 5. Small selection errors will compound and degrade stabilization quality.';
                '';
                ' REQUIREMENTS:';
                '';
                ' - MATLAB with Image Processing Toolbox.';
                ' - Input frames must be in PNG format and sequentially numbered.';
                ' - User patience and precision during point selection process.';
                '';
                ' USAGE EXAMPLE:';
                '   1. Click "Select input folder" to choose frame directory.';
                '   2. Select output folder for stabilized video.';
                '   3. Set desired output FPS.';
                '   4. Click "Start" and specify frame range for alignment.';
                '   5. For each frame: zoom/pan to locate reference point, press ENTER, then click precisely.';
                '   6. Repeat point selection process for all frames in the range.';
                '   7. Application automatically processes and saves stabilized video.';
                '';
                ' WARNING:';
                ' The quality of stabilization directly depends on the precision of point selection.';
                ' Inconsistent point selection will result in poor stabilization and may make the';
                ' output video worse than the original. Take time to be precise!';
                '';
                ' ==============================================================================';
                };

            % Create a dialog box with the text
            fig = uifigure('Name', 'STABM Manual Stabilization Tool Info', 'Position', [100 100 800 650]);
            txt = uitextarea(fig, 'Value', infoText, 'Position', [20 20 760 580], ...
                'FontName', 'FixedWidth', 'Editable', 'off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectoutputfolderButton = uibutton(app.UIFigure, 'push');
            app.SelectoutputfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectoutputfolderButtonPushed, true);
            app.SelectoutputfolderButton.Text = 'Select output folder';
            app.SetoutputfpsButton = uibutton(app.UIFigure, 'push');
            app.SetoutputfpsButton.ButtonPushedFcn = createCallbackFcn(app, @SetoutputfpsButtonPushed, true);
            app.SetoutputfpsButton.Text = 'Set output fps';
            app.FromEditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = 'Suggested:';
            app.FromEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.Value = 'Selected:';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SelectinputfolderButton = uibutton(app.UIFigure, 'push');
            app.SelectinputfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectinputfolderButtonPushed, true);
            app.SelectinputfolderButton.Text = 'Select input folder';
            app.ManualAlignmentButton = uibutton(app.UIFigure, 'push');
            app.ManualAlignmentButton.ButtonPushedFcn = createCallbackFcn(app, @ManualAlignmentButtonPushed, true);
            app.ManualAlignmentButton.Text = 'Manual Alignment';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = STABM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end